classdef CommonWirelessSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CommonWirelessSimulatorUIFigure  matlab.ui.Figure
        DeleteSelectedConfigsCheckBox  matlab.ui.control.CheckBox
        DatabaseTableDropDown          matlab.ui.control.DropDown
        DatabaseTableDropDownLabel     matlab.ui.control.Label
        IterativelyRenderCheckBox      matlab.ui.control.CheckBox
        ReloadProfilesButton           matlab.ui.control.Button
        GenerateFigureButton           matlab.ui.control.Button
        SimulateButton                 matlab.ui.control.Button
        NumberofFramesEditField        matlab.ui.control.NumericEditField
        NumberofFramesEditFieldLabel   matlab.ui.control.Label
        ProfileSelectDropDown          matlab.ui.control.DropDown
        ProfileSelectDropDownLabel     matlab.ui.control.Label
        EnableMySQLCheckBox            matlab.ui.control.CheckBox
        SaveExcelCheckBox              matlab.ui.control.CheckBox
        FramesIterationEditField       matlab.ui.control.NumericEditField
        FramesIterationEditFieldLabel  matlab.ui.control.Label
        ParallelizationCheckBox        matlab.ui.control.CheckBox
        SavePriorityDropDown           matlab.ui.control.DropDown
        SavePriorityDropDownLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            clc;
            [~,profile_names] = saved_profiles();
            fprintf("___________________________________________________\n")
            fprintf("|__________________________________________________|\n")
            fprintf("||                                                ||\n")
            fprintf("|| Saved Profiles:                                ||\n")
            fprintf("||________________________________________________||\n")
            for i = 1:length(profile_names)
                fprintf("||                                                ||\n")
                row = sprintf("|| %d:",i);
                fprintf("%s",row)
                for j = 1:(50-length(char(row)))
                    fprintf(" ")
                end
                fprintf("||\n")
                row = sprintf("|| %s",profile_names{i});
                fprintf("%s",row)
                for j = 1:(50-length(char(row)))
                    fprintf(" ")
                end
                fprintf("||\n")
            end
            fprintf("||________________________________________________||\n")
            fprintf("||________________________________________________||\n")
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

            app_settings.table_name = app.DatabaseTableDropDown.Value;
            app_settings.use_parellelization = app.ParallelizationCheckBox.Value;
            app_settings.frames_per_iter = app.FramesIterationEditField.Value;
            app_settings.priority = app.SavePriorityDropDown.Value;
            app_settings.save_mysql = app.EnableMySQLCheckBox.Value;
            app_settings.save_excel = app.SaveExcelCheckBox.Value;
            app_settings.profile_sel = str2double(app.ProfileSelectDropDown.Value);
            app_settings.num_frames = app.NumberofFramesEditField.Value;
            app_settings.iteratively_render = app.IterativelyRenderCheckBox.Value;
            app_settings.delete_sel = app.DeleteSelectedConfigsCheckBox.Value;

            sim_head(app_settings)
        end

        % Value changed function: NumberofFramesEditField
        function NumberofFramesEditFieldValueChanged(app, event)

        end

        % Button pushed function: GenerateFigureButton
        function GenerateFigureButtonPushed(app, event)
            
            app_settings.table_name = app.DatabaseTableDropDown.Value;
            app_settings.use_parellelization = false;
            app_settings.frames_per_iter = app.FramesIterationEditField.Value;
            app_settings.priority = app.SavePriorityDropDown.Value;
            app_settings.save_mysql = app.EnableMySQLCheckBox.Value;
            app_settings.save_excel = app.SaveExcelCheckBox.Value;
            app_settings.profile_sel = str2double(app.ProfileSelectDropDown.Value);
            app_settings.num_frames = 0;
            app_settings.iteratively_render = false;
            app_settings.delete_sel = false;

            sim_head(app_settings)

        end

        % Button pushed function: ReloadProfilesButton
        function ReloadProfilesButtonPushed(app, event)
            
            clc;
            [~,profile_names] = saved_profiles();
            fprintf("___________________________________________________\n")
            fprintf("|__________________________________________________|\n")
            fprintf("||                                                ||\n")
            fprintf("|| Saved Profiles:                                ||\n")
            fprintf("||________________________________________________||\n")
            for i = 1:length(profile_names)
                fprintf("||                                                ||\n")
                row = sprintf("|| %d:",i);
                fprintf("%s",row)
                for j = 1:(50-length(char(row)))
                    fprintf(" ")
                end
                fprintf("||\n")
                row = sprintf("|| %s",profile_names{i});
                fprintf("%s",row)
                for j = 1:(50-length(char(row)))
                    fprintf(" ")
                end
                fprintf("||\n")
            end
            fprintf("||________________________________________________||\n")
            fprintf("||________________________________________________||\n")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CommonWirelessSimulatorUIFigure = uifigure('Visible', 'off');
            app.CommonWirelessSimulatorUIFigure.Name = 'Common Wireless Simulator';
            app.SavePriorityDropDownLabel = uilabel(app.CommonWirelessSimulatorUIFigure);
            app.SavePriorityDropDownLabel.Text = 'Save Priority';
            app.SavePriorityDropDown = uidropdown(app.CommonWirelessSimulatorUIFigure);
            app.SavePriorityDropDown.Items = {'mysql', 'local'};
            app.SavePriorityDropDown.Value = 'mysql';
            app.ParallelizationCheckBox = uicheckbox(app.CommonWirelessSimulatorUIFigure);
            app.ParallelizationCheckBox.Text = 'Parallelization';
            app.ParallelizationCheckBox.Value = true;
            app.FramesIterationEditFieldLabel = uilabel(app.CommonWirelessSimulatorUIFigure);
            app.FramesIterationEditFieldLabel.Text = 'Frames/Iteration';
            app.FramesIterationEditField = uieditfield(app.CommonWirelessSimulatorUIFigure, 'numeric');
            app.FramesIterationEditField.Limits = [0 Inf];
            app.FramesIterationEditField.Value = 10;
            app.SaveExcelCheckBox = uicheckbox(app.CommonWirelessSimulatorUIFigure);
            app.SaveExcelCheckBox.Text = 'Save Excel';
            app.EnableMySQLCheckBox = uicheckbox(app.CommonWirelessSimulatorUIFigure);
            app.EnableMySQLCheckBox.Text = 'Enable MySQL';
            app.EnableMySQLCheckBox.Value = true;
            app.ProfileSelectDropDownLabel = uilabel(app.CommonWirelessSimulatorUIFigure);
            app.ProfileSelectDropDownLabel.Text = 'Profile Select';
            app.ProfileSelectDropDown = uidropdown(app.CommonWirelessSimulatorUIFigure);
            app.ProfileSelectDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ProfileSelectDropDown.Value = '1';
            app.NumberofFramesEditFieldLabel = uilabel(app.CommonWirelessSimulatorUIFigure);
            app.NumberofFramesEditFieldLabel.Text = 'Number of Frames';
            app.NumberofFramesEditField = uieditfield(app.CommonWirelessSimulatorUIFigure, 'numeric');
            app.NumberofFramesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofFramesEditFieldValueChanged, true);
            app.NumberofFramesEditField.Value = 2000;
            app.SimulateButton = uibutton(app.CommonWirelessSimulatorUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.GenerateFigureButton = uibutton(app.CommonWirelessSimulatorUIFigure, 'push');
            app.GenerateFigureButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFigureButtonPushed, true);
            app.GenerateFigureButton.Text = 'Generate Figure';
            app.ReloadProfilesButton = uibutton(app.CommonWirelessSimulatorUIFigure, 'push');
            app.ReloadProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadProfilesButtonPushed, true);
            app.ReloadProfilesButton.Text = 'Reload Profiles';
            app.IterativelyRenderCheckBox = uicheckbox(app.CommonWirelessSimulatorUIFigure);
            app.IterativelyRenderCheckBox.Text = 'Iteratively Render';
            app.DatabaseTableDropDownLabel = uilabel(app.CommonWirelessSimulatorUIFigure);
            app.DatabaseTableDropDownLabel.Text = 'Database Table';
            app.DatabaseTableDropDown = uidropdown(app.CommonWirelessSimulatorUIFigure);
            app.DatabaseTableDropDown.Items = {'results_TWC', 'OTFS_Journal'};
            app.DatabaseTableDropDown.Value = 'results_TWC';
            app.DeleteSelectedConfigsCheckBox = uicheckbox(app.CommonWirelessSimulatorUIFigure);
            app.DeleteSelectedConfigsCheckBox.Text = {'Delete'; 'Selected'; 'Configs'};
            % Show the figure after all components are created
            app.CommonWirelessSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CommonWirelessSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CommonWirelessSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CommonWirelessSimulatorUIFigure)
        end
    end
end